classdef MuscleAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Muscle_image             matlab.ui.control.UIAxes
        SelectFolderButton       matlab.ui.control.Button
        MuscleThickness_image    matlab.ui.control.UIAxes
        MusclexpixelSliderLabel  matlab.ui.control.Label
        MusclexpixelSlider       matlab.ui.control.Slider
        FrameSliderLabel         matlab.ui.control.Label
        FrameSlider              matlab.ui.control.Slider
    end

    
    properties (Access = private)
        eco_matrix = [];
        memoria_fascia_sup_inf = [];
        Results = [];
        Muscle_x_pixel = [];
        Muscle_frame = 1;
    end
    
    methods (Access = private)
        
        function plot_eco_MT(app)
            %Plotear eco con la linea donde se realiza la medida y MT vs frame
            
            %eco
            imshow(app.eco_matrix(:,:,app.Muscle_frame),imref2d(size(app.eco_matrix(:,:,1)),app.Results.Scale_factor,app.Results.Scale_factor), 'Parent', app.Muscle_image)
            hold(app.Muscle_image, 'on')
                plot(app.Muscle_image,[app.MusclexpixelSlider.Value app.MusclexpixelSlider.Value] * app.Results.Scale_factor,[app.memoria_fascia_sup_inf(app.Muscle_frame,app.MusclexpixelSlider.Value,2) app.memoria_fascia_sup_inf(app.Muscle_frame,app.MusclexpixelSlider.Value,1)],'yo','LineWidth',3)
                plot(app.Muscle_image,[app.MusclexpixelSlider.Value app.MusclexpixelSlider.Value] * app.Results.Scale_factor,[app.memoria_fascia_sup_inf(app.Muscle_frame,app.MusclexpixelSlider.Value,2) app.memoria_fascia_sup_inf(app.Muscle_frame,app.MusclexpixelSlider.Value,1)],'y-','LineWidth',3)
                plot(app.Muscle_image,(1:size(app.eco_matrix(:,:,app.Muscle_frame),2)) .* app.Results.Scale_factor, app.memoria_fascia_sup_inf(app.Muscle_frame,:,1),'r--','LineWidth',3)
                plot(app.Muscle_image,(1:size(app.eco_matrix(:,:,app.Muscle_frame),2)) .* app.Results.Scale_factor, app.memoria_fascia_sup_inf(app.Muscle_frame,:,2),'r--','LineWidth',3)
            hold(app.Muscle_image, 'off')
            
            %MT vs frame
            plot(app.MuscleThickness_image,(1:size(app.memoria_fascia_sup_inf,1)),(app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,1)),'LineWidth',2) 
            hold(app.MuscleThickness_image, 'on')
                xline(app.MuscleThickness_image,app.Muscle_frame,'-.','LineWidth',2);
%                 xline(locs(1),'--','LineWidth',2);
%                 xline(locs(2),'--','LineWidth',2);
            hold(app.MuscleThickness_image, 'off')
        end
    end
    

    methods (Access = private)

        % Callback function
        function FrameSliderValueChanged(app, event)
            
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            %[eco_matrix_name,path_v] = uigetfile('*.*','Select Video File');
            path_v = uigetdir();
            app.eco_matrix = load(strcat(path_v,'\eco_memory.mat'));
            app.eco_matrix = app.eco_matrix.eco_memory;
            app.memoria_fascia_sup_inf = load(strcat(path_v,'\memoria_fascia_sup_inf.mat'));
            app.memoria_fascia_sup_inf = app.memoria_fascia_sup_inf.memoria_fascia_sup_inf;
            app.Results = load(strcat(path_v,'\Results.mat'));
            app.Results = app.Results.Results;
            app.Muscle_x_pixel = app.Results.Muscle_x_pixel;
            app.MusclexpixelSlider.Limits = [1 size(app.memoria_fascia_sup_inf,2)];
            app.MusclexpixelSlider.Value = app.Muscle_x_pixel;
            app.FrameSlider.Limits = [1 size(app.memoria_fascia_sup_inf,1)];
            app.FrameSlider.Value = app.Muscle_frame;
            app.Muscle_image.YLim = [0 size(app.eco_matrix,1) * app.Results.Scale_factor];
            app.Muscle_image.XLim = [0 size(app.eco_matrix,2) * app.Results.Scale_factor];
            app.plot_eco_MT
            
        end

        % Callback function
        function FrameSpinnerValueChanged(app, event)
            
        end

        % Value changed function: MusclexpixelSlider
        function MusclexpixelSliderValueChanged(app, event)
            value = app.MusclexpixelSlider.Value;
            app.Muscle_x_pixel = round(value);
            app.MusclexpixelSlider.Value = app.Muscle_x_pixel; 
            app.plot_eco_MT
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged2(app, event)
            value = app.FrameSlider.Value;
            app.Muscle_frame = round(value);
            app.FrameSlider.Value = app.Muscle_frame;
            app.plot_eco_MT
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Muscle_image = uiaxes(app.UIFigure);
            title(app.Muscle_image, 'Muscle')
            xlabel(app.Muscle_image, '[mm]')
            ylabel(app.Muscle_image, '[mm]')
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.MuscleThickness_image = uiaxes(app.UIFigure);
            title(app.MuscleThickness_image, 'Muscle Thickness')
            xlabel(app.MuscleThickness_image, 'Frame')
            ylabel(app.MuscleThickness_image, '[mm]')
            app.MusclexpixelSliderLabel = uilabel(app.UIFigure);
            app.MusclexpixelSliderLabel.Text = 'Muscle x pixel';
            app.MusclexpixelSlider = uislider(app.UIFigure);
            app.MusclexpixelSlider.ValueChangedFcn = createCallbackFcn(app, @MusclexpixelSliderValueChanged, true);
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged2, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = MuscleAnalysisApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end